classdef PFG_tool_for_TPCs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerFlowGraphToolforDCDCConvertersUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        ResetButton                     matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        OptimizationStudyButton         matlab.ui.control.RadioButton
        PFGwithFCButton                 matlab.ui.control.RadioButton
        PFGButton                       matlab.ui.control.RadioButton
        RunScriptButton                 matlab.ui.control.Button
        OutputDropDown                  matlab.ui.control.DropDown
        OutputDropDownLabel             matlab.ui.control.Label
        StorageDropDown                 matlab.ui.control.DropDown
        StorageDropDownLabel            matlab.ui.control.Label
        RESInputsDropDown               matlab.ui.control.DropDown
        RESInputsDropDownLabel          matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GOButton                        matlab.ui.control.Button
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        DisplayedButton                 matlab.ui.control.RadioButton
        AllButton                       matlab.ui.control.RadioButton
        ExportResultstoWorkspaceButton  matlab.ui.control.Button
        PrevButton                      matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        AboutTextArea                   matlab.ui.control.TextArea
        AboutTextAreaLabel              matlab.ui.control.Label
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        LEGENDTextArea                  matlab.ui.control.TextArea
        LEGENDTextAreaLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
       res = 1 %number of RES port (currently can only be 1)
       storage ="EB" %type of storage port
       output = "Unidirectional"  % Output power path: Unidirectional/Bidirectional
       table_i = 1; %index of current result displayed in gui table and plot
       n = cell(0); %solution set for power flow configurations
       fc = cell(0);%solution set for power flow configurations with FCs
       full_graph = cell(0);
       full_pfgam = cell(0);
       pfgs={'A', 'B', 'C', 'D', 'E','F', 'G'}; %labels for SubGraphs to be used for display
       fcs = {'a', 'b', 'c', 'd', 'e', 'f'}; %labels for fcs to be used for display
       Bidi_Ports = 0; %algorithm value decided based on app.storage and app.output selections
       export_res = "All"; %indicates whether the user wants to send all or currently displayed results to MATLAB workspace
    end
    
    methods (Access = private)
        
        function results = Set_Bidi_Ports(app) %Set the variable Bidi_Ports used by algorithm and described in paper
            % EB+Unidirectional Output --> Bidi_Ports = 0
            % ESS+Unidirectional Output--> Bidi_Ports = 1
            % ESS+Bidirectional  Output--> Bidi_Ports = 2
            if app.storage=="ESS"
                if app.output=="Unidirectional"
                    app.Bidi_Ports = 1;
               
                else 
                    app.Bidi_Ports = 2;
                end
            else
                app.Bidi_Ports = 0;
            end
        end
        
        
        function reset_op = resetop(app) %Resets the gui
            %The gui returns to its intial state. Only PFG analysis can be
            %performed first
            app.PFGwithFCButton.Enable = 'off'; %PFG analysis must be performed first
            app.OptimizationStudyButton.Enable = 'off';%PFG analysis must be performed first
            app.PFGButton.Value = true; 
            cla(app.UIAxes); %clear plot
            app.UITable.Data = {}; %clear table    
            app.Label.Text = '/';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RESInputsDropDown
        function RESInputsDropDownValueChanged(app, event)
            app.res = app.RESInputsDropDown.Value; %res port number has changed. Update.
            Set_Bidi_Ports(app); %update the Bidi_Ports value
            resetop(app); %New Bidi_Ports --> New analysis needed ->reset gui
        end

        % Value changed function: StorageDropDown
        function StorageDropDownValueChanged(app, event)
            app.storage = app.StorageDropDown.Value; %ess port type has changed. Update.
            if app.storage == "EB" %in case of EB type, only unidirectional output is considered
                app.OutputDropDown.Value = "Unidirectional";                
            end
            Set_Bidi_Ports(app); %update the Bidi_Ports value
            resetop(app); %New Bidi_Ports --> New analysis needed ->reset gui
        end

        % Value changed function: OutputDropDown
        function OutputDropDownValueChanged(app, event)
            app.output = app.OutputDropDown.Value; %output port type has changed. Update.
            if app.storage == "EB" %in case of EB type, only unidirectional output is considered
                app.OutputDropDown.Value = "Unidirectional";                
            end
            Set_Bidi_Ports(app); %update the Bidi_Ports value
            resetop(app); %New Bidi_Ports --> New analysis needed ->reset gui
        end

        % Button pushed function: RunScriptButton
        function RunScriptButtonPushed(app, event)
            %Run Button was pressed to perform an analysis
            if app.PFGButton.Value == true   %only power flow confiurations will be found          
                app.n=Create_PFG(app.Bidi_Ports); %call the relevant function and store the results in app.n
                %app.full_pfgam = Create_Full_PFGAM(app.n);
                data = app.n{1,app.table_i}; %store the element with index table_i of app.n array in data   
                
                app.UITable.Data = data; %display data on table element 
                app.Label.Text = strcat(strcat(int2str(app.table_i),'/'),int2str(length(app.n)));
                app.UITable.ColumnName = app.pfgs(1:size(data,2)); % set column names on table element
                app.UITable.RowName = {'RES', 'Storage', 'Output', 'Type', 'Bidi_Port'}; %set row names on table element
                app.PFGwithFCButton.Enable = 'on'; %this analysis is performed, so the next level analysis can be performed for the same input parameters
            elseif app.PFGwithFCButton.Value == true %power flow configurations with FCs will be found
                app.fc = Place_FCs(app.n, app.Bidi_Ports); %call the relevant function and store the results in app.fc
                [app.full_graph, app.full_pfgam] = Create_Full_PFGAM(app.fc);
                data = app.fc{1,app.table_i};
                app.UITable.Data = data;
                app.OptimizationStudyButton.Enable = 'on';
                app.Label.Text = strcat(strcat(int2str(app.table_i),'/'),int2str(length(app.fc)));
                app.UITable.ColumnName = app.pfgs(1:size(data,2)); % set column names on table element
                app.UITable.RowName = [['RES', 'Storage', 'Output'] app.fcs(1:size(data,1)-3)]; %set row names on table element
                [row, column] = size(data);
                G = app.full_graph{1,app.table_i};
                P = {'RES', 'Storage', 'Output'}; PF = {'A', 'B', 'C', 'D', 'E'}; FC = {'a', 'b', 'c', 'd','e'};
                nLabels = [P PF(1:column-3) FC(1:row-3)]; %graph node names
                plot(app.UIAxes,G,'Layout','force', 'NodeLabel',nLabels,'NodeFontSize',7,'ArrowSize',5);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetop(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.table_i=app.table_i+1; %counter to display one case each time
            if app.PFGButton.Value == true %Level 1
                if app.table_i==length(app.n)+1
                    app.table_i = 1;
                end
                data = app.n{1,app.table_i}; %derive n:power flow configurations
                app.Label.Text = strcat(strcat(int2str(app.table_i),'/'),int2str(length(app.n)));
            elseif app.PFGwithFCButton.Value == true %Level 2
                if app.table_i==length(app.fc)+1
                    app.table_i = 1;
                end
                data = app.fc{1,app.table_i}; %derive fc:power flow configurations with fcs
                app.Label.Text = strcat(strcat(int2str(app.table_i),'/'),int2str(length(app.fc)));
                [row, column] = size(data);
                G = app.full_graph{1,app.table_i};
                P = {'RES', 'Storage', 'Output'}; PF = {'A', 'B', 'C', 'D', 'E'}; FC = {'a', 'b', 'c', 'd','e'};
                nLabels = [P PF(1:column-3) FC(1:row-3)]; %graph node names
                app.UITable.RowName = [P FC(1:row-3)]; %row names
                app.UITable.ColumnName = [PF(1:column-3) P]; %column names
                plot(app.UIAxes,G,'Layout','force', 'NodeLabel',nLabels,'NodeFontSize',7,'ArrowSize',5);
            end
            app.UITable.Data = data; %pass data(n or fc) to the table
            
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            app.table_i=app.table_i-1;
            
            if app.table_i==0
                app.table_i = length(app.n);
            end
            if app.PFGButton.Value == true %Level 1
                if app.table_i==0
                    app.table_i = length(app.n);
                end
                data = app.n{1,app.table_i}; %derive n:power flow configurations
                app.Label.Text = strcat(strcat(int2str(app.table_i),'/'),int2str(length(app.n)));
            elseif app.PFGwithFCButton.Value == true %Level 2
                if app.table_i==0
                    app.table_i = length(app.fc);
                end
                data = app.fc{1,app.table_i}; %derive fc:power flow configurations with fcs
                app.Label.Text = strcat(strcat(int2str(app.table_i),'/'),int2str(length(app.fc)));
                [row, column] = size(data);
                G = app.full_graph{1,app.table_i};
                P = {'RES', 'Storage', 'Output'}; PF = {'A', 'B', 'C', 'D', 'E'}; FC = {'a', 'b', 'c', 'd','e'};
                nLabels = [P PF(1:column-3) FC(1:row-3)]; %graph node names
                app.UITable.RowName = [P FC(1:row-3)]; %row names
                app.UITable.ColumnName = [PF(1:column-3) P]; %column names
                plot(app.UIAxes,G,'Layout','force', 'NodeLabel',nLabels,'NodeFontSize',7,'ArrowSize',5);
            end
            app.UITable.Data = data; %pass data(n or fc) to the table
        end

        % Button pushed function: ExportResultstoWorkspaceButton
        function ExportResultstoWorkspaceButtonPushed(app, event)
            %provides the option to export the results to workspace for further processing
            app.ButtonGroup_2.Visible = true; %choose between exporting the single pfg displayed, or all the results
            app.GOButton.Visible = true; %press the button to proceed
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            app.export_res = selectedButton;
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            if app.export_res == "All"
                if app.PFGButton.Value == true  
                    assignin('base', 'pfgs', app.n);
                elseif app.PFGwithFCButton.Value == true
                    assignin('base', 'pfgs_with_fcs', app.fc);
                    assignin('base', 'full_graph', app.full_graph);
                    assignin('base', 'full_pfgam', app.full_pfgam);
                end
            else
                if app.PFGButton.Value == true  
                    assignin('base', 'pfg_single', app.n{1,app.table_i});
                elseif app.PFGwithFCButton.Value == true
                    assignin('base', 'pfg_with_fcs_single', app.fc{1,app.table_i});
                    assignin('base', 'full_pfgam_single', app.full_pfgam{1,app.table_i});
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PowerFlowGraphToolforDCDCConvertersUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PowerFlowGraphToolforDCDCConvertersUIFigure = uifigure('Visible', 'off');
            app.PowerFlowGraphToolforDCDCConvertersUIFigure.Name = 'Power Flow Graph Tool for DC-DC Converters';
            app.PowerFlowGraphToolforDCDCConvertersUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PowerFlowGraphToolforDCDCConvertersUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RESInputsDropDownLabel = uilabel(app.LeftPanel);
            app.RESInputsDropDownLabel.Text = 'RES Inputs';
            app.RESInputsDropDown = uidropdown(app.LeftPanel);
            app.RESInputsDropDown.Items = {'1'};
            app.RESInputsDropDown.ValueChangedFcn = createCallbackFcn(app, @RESInputsDropDownValueChanged, true);
            app.RESInputsDropDown.Tag = 'RES';
            app.RESInputsDropDown.Value = '1';
            app.StorageDropDownLabel = uilabel(app.LeftPanel);
            app.StorageDropDownLabel.Text = 'Storage ';
            app.StorageDropDown = uidropdown(app.LeftPanel);
            app.StorageDropDown.Items = {'ESS', 'EB'};
            app.StorageDropDown.ValueChangedFcn = createCallbackFcn(app, @StorageDropDownValueChanged, true);
            app.StorageDropDown.Tag = 'Storage';
            app.StorageDropDown.Value = 'ESS';
            app.OutputDropDownLabel = uilabel(app.LeftPanel);
            app.OutputDropDownLabel.Text = 'Output';
            app.OutputDropDown = uidropdown(app.LeftPanel);
            app.OutputDropDown.Items = {'Unidirectional', 'Bidirectional'};
            app.OutputDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDropDownValueChanged, true);
            app.OutputDropDown.Tag = 'Output';
            app.OutputDropDown.Value = 'Unidirectional';
            app.RunScriptButton = uibutton(app.LeftPanel, 'push');
            app.RunScriptButton.ButtonPushedFcn = createCallbackFcn(app, @RunScriptButtonPushed, true);
            app.RunScriptButton.Text = 'Run Script';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.PFGButton = uiradiobutton(app.ButtonGroup);
            app.PFGButton.Text = 'PFG';
            app.PFGButton.Value = true;
            app.PFGwithFCButton = uiradiobutton(app.ButtonGroup);
            app.PFGwithFCButton.Enable = 'off';
            app.PFGwithFCButton.Text = 'PFG with FC';
            app.OptimizationStudyButton = uiradiobutton(app.ButtonGroup);
            app.OptimizationStudyButton.Enable = 'off';
            app.OptimizationStudyButton.Text = 'Optimization Study';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'logo.png';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, {'Power Flow Configuration Graph'; ''})
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = {''; ''};
            app.NextButton = uibutton(app.CenterPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.CenterPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.ExportResultstoWorkspaceButton = uibutton(app.CenterPanel, 'push');
            app.ExportResultstoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultstoWorkspaceButtonPushed, true);
            app.ExportResultstoWorkspaceButton.Text = 'Export Results to Workspace';
            app.ButtonGroup_2 = uibuttongroup(app.CenterPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Visible = 'off';
            app.AllButton = uiradiobutton(app.ButtonGroup_2);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.DisplayedButton = uiradiobutton(app.ButtonGroup_2);
            app.DisplayedButton.Text = 'Displayed';
            app.GOButton = uibutton(app.CenterPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Visible = 'off';
            app.GOButton.Text = 'GO!';
            app.RightPanel = uipanel(app.GridLayout);
            app.LEGENDTextAreaLabel = uilabel(app.RightPanel);
            app.LEGENDTextAreaLabel.Text = 'LEGEND';
            app.LEGENDTextArea = uitextarea(app.RightPanel);
            app.LEGENDTextArea.Value = {'-Fundamental Converters are denoted with lowercase letters a, b, etc.'; ''; '-Sub Graphs are denoted with capital letter A, B, etc.'};
            app.Hyperlink = uihyperlink(app.RightPanel);
            app.Hyperlink.URL = 'https://github.com/gsalagiannis/Algorithmic-Extraction-of-Power-Flow-Configurations-for-Three-Port-DC-DC-Converters';
            app.Hyperlink.Text = 'Github Repo';
            app.Hyperlink2 = uihyperlink(app.RightPanel);
            app.Hyperlink2.URL = 'http://www.lemec.ece.upatras.gr/www/';
            app.Hyperlink2.Text = 'Upatras LEMEC';
            app.AboutTextAreaLabel = uilabel(app.RightPanel);
            app.AboutTextAreaLabel.Text = 'About';
            app.AboutTextArea = uitextarea(app.RightPanel);
            app.AboutTextArea.Value = {'This tool has been created by G. Salagiannis and E. Tatakis from the Laboratory of Electromechanical Energy Conversion of University of Patras, Greece, as a practical way to utilize the algorithm proposed and presented in ECCE Europe''24.'};
            % Show the figure after all components are created
            app.PowerFlowGraphToolforDCDCConvertersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PFG_tool_for_TPCs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PowerFlowGraphToolforDCDCConvertersUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerFlowGraphToolforDCDCConvertersUIFigure)
        end
    end
end